classdef nxs_cmp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        data1Label                   matlab.ui.control.Label
        data1path                    matlab.ui.control.EditField
        data2EditFieldLabel          matlab.ui.control.Label
        data2path                    matlab.ui.control.EditField
        seachdata1                   matlab.ui.control.Button
        seachdata2                   matlab.ui.control.Button
        EditsignalsLabel             matlab.ui.control.Label
        saveconfig                   matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        subsampletoHzEditFieldLabel  matlab.ui.control.Label
        srate                        matlab.ui.control.NumericEditField
        dc1SpinnerLabel              matlab.ui.control.Label
        dc1                          matlab.ui.control.Spinner
        dc2Label                     matlab.ui.control.Label
        dc2                          matlab.ui.control.Spinner
        scale1Label                  matlab.ui.control.Label
        scale1                       matlab.ui.control.Spinner
        scale2Label                  matlab.ui.control.Label
        scale2                       matlab.ui.control.Spinner
        loadconfigButton             matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        rmfirstsamples1Label         matlab.ui.control.Label
        rm_s1                        matlab.ui.control.Spinner
        rmfirstsamples2Label         matlab.ui.control.Label
        rm_s2                        matlab.ui.control.Spinner
        rmlastsamples1Label          matlab.ui.control.Label
        rm_e1                        matlab.ui.control.Spinner
        rmlastsamples2Label          matlab.ui.control.Label
        rm_e2                        matlab.ui.control.Spinner
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        custom_path % added path
        data1
        data2
    end
    
    methods (Access = private)
        
        function config_gui(app)
            if isempty(app.data1path.Value) || isempty(app.data2path.Value)
                return
            end
            
            
            app.update_plot();
        end
        
        
        function update_plot(app)
            cla(app.UIAxes);
            plot(app.UIAxes, (app.data1((app.rm_s1.Value+1):end-app.rm_e1.Value)+app.dc1.Value)*app.scale1.Value);
            hold(app.UIAxes, 'on')
            plot(app.UIAxes,  (app.data2((app.rm_s2.Value+1):end-app.rm_e2.Value)+app.dc2.Value)*app.scale2.Value);
            %drawnow
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath([fileparts(mfilename('fullpath')) filesep '..']);
            app.custom_path = reylab_custompath('useful_functions');
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {478, 478};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {198, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: seachdata1
        function seachdata1Pushed(app, event)
            [FILENAME, PATHNAME] = uigetfile('*.NC*', 'Select first NCx file');
            data1file = [PATHNAME, FILENAME];
            app.data1path.Value = data1file;
            
            [app.data1,metadata] = read_nsx_file(data1file);
            
            if app.srate.Value > metadata.sr
                error('choose a smaller sampling rate.')
            elseif app.srate.Value < metadata.sr
                subsampling = round(metadata.sr/app.srate.Value);
                app.data1 = app.data1(1:subsampling:end);
                metadata.lts = length(app.data1);
            end
            
            app.config_gui()
        end

        % Button pushed function: seachdata2
        function seachdata2ButtonPushed(app, event)
            [FILENAME, PATHNAME] = uigetfile('*.NC*',  'Select second NCx file');
            data2file = [PATHNAME, FILENAME];
            app.data2path.Value = data2file;
            [app.data2,metadata] = read_nsx_file(data2file);
            
            if app.srate.Value > metadata.sr
                error('choose a smaller sampling rate.')
            elseif app.srate.Value < metadata.sr
                subsampling = round(metadata.sr/app.srate.Value);
                app.data2 = app.data2(1:subsampling:end);
                metadata.lts = length(app.data2);
            end
            app.config_gui()
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.custom_path.rm()
            delete(app)
            
        end

        % Value changed function: dc1
        function dc1ValueChanged(app, event)
            value = app.dc1.Value;
            app.update_plot();
        end

        % Value changed function: dc2
        function dc2ValueChanged(app, event)
            value = app.dc2.Value;
            app.update_plot();
        end

        % Value changed function: scale1
        function scale1ValueChanged(app, event)
            value = app.scale1.Value;
            app.update_plot();
        end

        % Value changed function: scale2
        function scale2ValueChanged(app, event)
            value = app.scale2.Value;
            app.update_plot();
        end

        % Value changed function: rm_s1
        function rm_s1ValueChanged(app, event)
            value = app.rm_s1.Value;
            app.update_plot();
        end

        % Value changed function: rm_s2
        function rm_s2ValueChanged(app, event)
            value = app.rm_s2.Value;
            app.update_plot();
        end

        % Button pushed function: saveconfig
        function saveconfigButtonPushed(app, event)
            [file,path] = uiputfile('*.mat');
            allconfig = struct;
            
            
            allconfig.data1path = app.data1path.Value;
            allconfig.data2path = app.data2path.Value;
            
            allconfig.srate = app.srate.Value;
            
            allconfig.dc1 = app.dc1.Value;
            allconfig.dc2 = app.dc2.Value;
            
            allconfig.scale1 = app.scale1.Value;
            allconfig.scale2 = app.scale2.Value;
            
            
            allconfig.rm_s1 = app.rm_s1.Value;
            allconfig.rm_s2 = app.rm_s2.Value;
            
            
            allconfig.rm_e1 = app.rm_e1.Value;
            allconfig.rm_e2 = app.rm_e2.Value;            
            save([path, file], 'allconfig');
        end

        % Button pushed function: loadconfigButton
        function loadconfigButtonPushed(app, event)
            %lo inverso a save data
            [file,path] = uigetfile('*.mat');
            load([path, file], 'allconfig');
            
            app.data1path.Value = allconfig.data1path;
            app.data2path.Value = allconfig.data2path;
            
            app.srate.Value = allconfig.srate;
            
            app.dc1.Value = allconfig.dc1;
            app.dc2.Value = allconfig.dc2;
            
            app.scale1.Value = allconfig.scale1;
            app.scale2.Value = allconfig.scale2;
            
            
            app.rm_s1.Value = allconfig.rm_s1;
            app.rm_s2.Value=allconfig.rm_s2;
            
            app.rm_e1.Value = allconfig.em_e1;
            app.rm_e2.Value=allconfig.rm_e2;
            
        end

        % Value changed function: rm_e1
        function rm_e1ValueChanged(app, event)
            value = app.rm_e1.Value;
            app.update_plot();
        end

        % Value changed function: rm_e2
        function rm_e2ValueChanged(app, event)
            value = app.rm_e2.Value;
            app.update_plot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.data1Label = uilabel(app.LeftPanel);
            app.data1Label.Text = 'data 1';
            app.data1path = uieditfield(app.LeftPanel, 'text');
            app.data1path.Editable = 'off';
            app.data2EditFieldLabel = uilabel(app.LeftPanel);
            app.data2EditFieldLabel.Text = 'data 2';
            app.data2path = uieditfield(app.LeftPanel, 'text');
            app.data2path.Editable = 'off';
            app.seachdata1 = uibutton(app.LeftPanel, 'push');
            app.seachdata1.ButtonPushedFcn = createCallbackFcn(app, @seachdata1Pushed, true);
            app.seachdata1.Text = 'seach';
            app.seachdata2 = uibutton(app.LeftPanel, 'push');
            app.seachdata2.ButtonPushedFcn = createCallbackFcn(app, @seachdata2ButtonPushed, true);
            app.seachdata2.Text = 'seach';
            app.EditsignalsLabel = uilabel(app.LeftPanel);
            app.EditsignalsLabel.Text = 'Edit signals:';
            app.saveconfig = uibutton(app.LeftPanel, 'push');
            app.saveconfig.ButtonPushedFcn = createCallbackFcn(app, @saveconfigButtonPushed, true);
            app.saveconfig.Text = 'save config';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '';
            app.subsampletoHzEditFieldLabel = uilabel(app.LeftPanel);
            app.subsampletoHzEditFieldLabel.Text = 'subsample to (Hz):';
            app.srate = uieditfield(app.LeftPanel, 'numeric');
            app.srate.RoundFractionalValues = 'on';
            app.srate.ValueDisplayFormat = '%d';
            app.srate.Value = 10000;
            app.dc1SpinnerLabel = uilabel(app.LeftPanel);
            app.dc1SpinnerLabel.Text = '+ dc 1';
            app.dc1 = uispinner(app.LeftPanel);
            app.dc1.ValueChangedFcn = createCallbackFcn(app, @dc1ValueChanged, true);
            app.dc2Label = uilabel(app.LeftPanel);
            app.dc2Label.Text = '+ dc 2';
            app.dc2 = uispinner(app.LeftPanel);
            app.dc2.ValueChangedFcn = createCallbackFcn(app, @dc2ValueChanged, true);
            app.scale1Label = uilabel(app.LeftPanel);
            app.scale1Label.Text = '* scale 1';
            app.scale1 = uispinner(app.LeftPanel);
            app.scale1.ValueChangedFcn = createCallbackFcn(app, @scale1ValueChanged, true);
            app.scale1.Value = 1;
            app.scale2Label = uilabel(app.LeftPanel);
            app.scale2Label.Text = '* scale 2';
            app.scale2 = uispinner(app.LeftPanel);
            app.scale2.ValueChangedFcn = createCallbackFcn(app, @scale2ValueChanged, true);
            app.scale2.Value = 1;
            app.loadconfigButton = uibutton(app.LeftPanel, 'push');
            app.loadconfigButton.ButtonPushedFcn = createCallbackFcn(app, @loadconfigButtonPushed, true);
            app.loadconfigButton.Text = 'load config';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'samples')
            ylabel(app.UIAxes, '')
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.ALim = [0 1];
            app.rmfirstsamples1Label = uilabel(app.RightPanel);
            app.rmfirstsamples1Label.Text = 'rm first samples 1';
            app.rm_s1 = uispinner(app.RightPanel);
            app.rm_s1.Step = 100;
            app.rm_s1.Limits = [0 Inf];
            app.rm_s1.ValueDisplayFormat = '%d';
            app.rm_s1.ValueChangedFcn = createCallbackFcn(app, @rm_s1ValueChanged, true);
            app.rmfirstsamples2Label = uilabel(app.RightPanel);
            app.rmfirstsamples2Label.Text = 'rm first samples 2';
            app.rm_s2 = uispinner(app.RightPanel);
            app.rm_s2.Step = 100;
            app.rm_s2.Limits = [0 Inf];
            app.rm_s2.ValueDisplayFormat = '%d';
            app.rm_s2.ValueChangedFcn = createCallbackFcn(app, @rm_s2ValueChanged, true);
            app.rmlastsamples1Label = uilabel(app.RightPanel);
            app.rmlastsamples1Label.Text = 'rm last samples 1';
            app.rm_e1 = uispinner(app.RightPanel);
            app.rm_e1.Step = 100;
            app.rm_e1.Limits = [0 Inf];
            app.rm_e1.ValueDisplayFormat = '%d';
            app.rm_e1.ValueChangedFcn = createCallbackFcn(app, @rm_e1ValueChanged, true);
            app.rmlastsamples2Label = uilabel(app.RightPanel);
            app.rmlastsamples2Label.Text = 'rm last samples 2';
            app.rm_e2 = uispinner(app.RightPanel);
            app.rm_e2.Step = 100;
            app.rm_e2.Limits = [0 Inf];
            app.rm_e2.ValueDisplayFormat = '%d';
            app.rm_e2.ValueChangedFcn = createCallbackFcn(app, @rm_e2ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nxs_cmp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end