classdef labjack_test_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        time_diff                      matlab.ui.control.Label
        get_values_2                   matlab.ui.control.Label
        mspulses                       matlab.ui.control.NumericEditField
        msbetweenpulsesEditFieldLabel  matlab.ui.control.Label
        values2send                    matlab.ui.control.EditField
        valuestosendEditFieldLabel     matlab.ui.control.Label
        get_values                     matlab.ui.control.Label
        GetparallelvaluesButton        matlab.ui.control.Button
        LabjackRIppleTestLabel         matlab.ui.control.Label
        closeconnectionButton          matlab.ui.control.Button
        openButton                     matlab.ui.control.Button
        sendvalueButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        lj % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: openButton
        function openButtonPushed(app, event)
            app.lj = labJack('verbose',true); %open labJack verbosely
            if ~app.lj.validHandle()
                error('device not connected');
            end
            app.lj.setDIODirection([255,255,0],[255,255,0]);
            addpath('/opt/Trellis/Tools/xippmex/')
            xippmex('tcp');
            xippmex('digin', 'bit-change', 1);
        end

        % Button pushed function: closeconnectionButton
        function closeconnectionButtonPushed(app, event)
            app.lj.close();
            xippmex('close');
        end

        % Button pushed function: sendvalueButton
        function sendvalueButtonPushed(app, event)
            values = str2double(strsplit(app.values2send.Value,','));
            values = abs(round(values(~isnan(values))));
            
            for i=1:numel(values)
                app.lj.setDIOValue([values(i),values(i),0],[255,255,0]);
                pause(app.mspulses.Value/1000)
            end
        end

        % Button pushed function: GetparallelvaluesButton
        function GetparallelvaluesButtonPushed(app, event)
            [~, timestamps, events] = xippmex('digin');
            reason = cell2mat({events.reason});
            parallel_ev = reason==1;
            if any(parallel_ev)
                values = cell2mat({events(parallel_ev).parallel});
                td = diff(timestamps(parallel_ev))/30;
                values = values(:);
            else
                values = [];
                td = [];
            end
            app.time_diff.Text = sprintf('%.2f, ',td);
            app.get_values.Text=sprintf('%d, ',values);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.sendvalueButton = uibutton(app.UIFigure, 'push');
            app.sendvalueButton.ButtonPushedFcn = createCallbackFcn(app, @sendvalueButtonPushed, true);
            app.sendvalueButton.Text = 'send value';
            app.openButton = uibutton(app.UIFigure, 'push');
            app.openButton.ButtonPushedFcn = createCallbackFcn(app, @openButtonPushed, true);
            app.openButton.Text = 'open';
            app.closeconnectionButton = uibutton(app.UIFigure, 'push');
            app.closeconnectionButton.ButtonPushedFcn = createCallbackFcn(app, @closeconnectionButtonPushed, true);
            app.closeconnectionButton.Text = 'close connection';
            app.LabjackRIppleTestLabel = uilabel(app.UIFigure);
            app.LabjackRIppleTestLabel.Text = 'Labjack/RIpple Test';
            app.GetparallelvaluesButton = uibutton(app.UIFigure, 'push');
            app.GetparallelvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @GetparallelvaluesButtonPushed, true);
            app.GetparallelvaluesButton.Text = 'Get parallel values';
            app.get_values = uilabel(app.UIFigure);
            app.get_values.Text = 'received values';
            app.valuestosendEditFieldLabel = uilabel(app.UIFigure);
            app.valuestosendEditFieldLabel.Text = 'values to send';
            app.values2send = uieditfield(app.UIFigure, 'text');
            app.msbetweenpulsesEditFieldLabel = uilabel(app.UIFigure);
            app.msbetweenpulsesEditFieldLabel.Text = 'ms between pulses';
            app.mspulses = uieditfield(app.UIFigure, 'numeric');
            app.mspulses.Limits = [10 Inf];
            app.mspulses.Value = 50;
            app.get_values_2 = uilabel(app.UIFigure);
            app.get_values_2.Text = 'Time diff (ms):';
            app.time_diff = uilabel(app.UIFigure);
            app.time_diff.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = labjack_test_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end